classdef arduinoAMC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        Capacitor1nFEditField       matlab.ui.control.NumericEditField
        Capacitor1nFEditFieldLabel  matlab.ui.control.Label
        Resistor1inkEditField       matlab.ui.control.NumericEditField
        Resistor1inkEditFieldLabel  matlab.ui.control.Label
        HighLowButton               matlab.ui.control.StateButton
        InitAPPButton               matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        LOGLabel                    matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        baseUrl = 'http://localhost:5000';
        toggleStat=0;
        fetchTimer 

    end
    
    methods (Access = public)
        
% Timer callback: Fetch the toggle parameter from the backend every 1 sec.
function autoFetchToggle(app)
    try
        % Get all parameters from the backend.
        params = webread([app.baseUrl '/parameters']);
        if isfield(params, 'toggle')
            newToggleVal = params.toggle.current;
            % Convert numeric value to logical
            if newToggleVal == 1
                newToggleBool = true;
            elseif newToggleVal == 0
                newToggleBool = false;
            else
                error('Unexpected toggle value: must be 0 or 1.');
            end
            % Update UI only if there's a change.
            if app.toggleStat ~= newToggleBool
                app.toggleStat = newToggleBool;
                app.HighLowButton.Value = newToggleBool;
                app.LOGLabel.Text = sprintf('Auto-update: Toggle set to %d', newToggleBool);
                                app.plotHighPassFilter;

            end
            numericFields = {...
            'Resistor1', 'Resistor1inkEditField'; ...
            'Capacitor1', 'Capacitor1nFEditField'};
        for i = 1:size(numericFields,1)
            paramName = numericFields{i,1};
            fieldName = numericFields{i,2};
            if isfield(params, paramName)
                newValue = params.(paramName).current;
                % Update the field if its value is different.
                if app.(fieldName).Value ~= newValue
                    app.(fieldName).Value = newValue;
                    app.LOGLabel.Text = sprintf('%s\nAuto-update: %s set to %d', ...
                        app.LOGLabel.Text, paramName, newValue);
                        app.plotHighPassFilter();

                end
            end
        end
        end
    catch ME
        % Log errors without stopping the timer.
        app.LOGLabel.Text = sprintf('Error fetching parameter: %s', ME.message);
    end
end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: InitAPPButton
        function configStartup(app, event)
    try
        toggleParam = struct('name','toggle','min',0,'max',1,'current',app.toggleStat);
        addResponse = webwrite([app.baseUrl '/parameters'], toggleParam, ...
            weboptions('MediaType','application/json','RequestMethod','post'));
        app.LOGLabel.Text = sprintf('Added toggle parameter:\nCommand: %s\nStatus: %s', ...
            addResponse.command, addResponse.status);
    catch ME
        app.LOGLabel.Text = ['Error adding toggle parameter: ' ME.message];
    end

    % --- Add numeric parameters with default 2 (range 1-100) ---
    numericParams = {...
        'Resistor1', app.Resistor1inkEditField.Value; ...
        'Capacitor1', app.Capacitor1nFEditField.Value};
    for k = 1:size(numericParams,1)
        paramName = numericParams{k,1};
        paramValue = numericParams{k,2};
        try
            numParam = struct('name', paramName, 'min', 1, 'max', 1000, 'current', paramValue);
            numResponse = webwrite([app.baseUrl '/parameters'], numParam, ...
                weboptions('MediaType','application/json','RequestMethod','post'));
            app.LOGLabel.Text = sprintf('%s\nAdded %s parameter: %s', ...
                app.LOGLabel.Text, paramName, numResponse.status);
        catch ME
            app.LOGLabel.Text = sprintf('%s\nError adding %s parameter: %s', ...
                app.LOGLabel.Text, paramName, ME.message);
        end
    end

    % --- Set software parameter ---
    try
        softwareData = struct('software_name', 'MATLAB_GUI');
        softwareResponse = webwrite([app.baseUrl '/software'], softwareData);
        app.LOGLabel.Text = sprintf('%s\nSoftware command: %s\nStatus: %s', ...
            app.LOGLabel.Text, softwareResponse.command, softwareResponse.status);
    catch ME
        app.LOGLabel.Text = ['Error setting software: ' ME.message];
    end

    % --- Start the auto-fetch timer if not already running ---
    if isempty(app.fetchTimer) || ~isvalid(app.fetchTimer)
        app.fetchTimer = timer(...
            'ExecutionMode', 'fixedRate', ...
            'Period', 1, ...
            'TimerFcn', @(~,~) app.autoFetchToggle());
        start(app.fetchTimer);
        app.LOGLabel.Text = sprintf('%s\nAuto-fetch timer started.', app.LOGLabel.Text);
    else
        app.LOGLabel.Text = sprintf('%s\nAuto-fetch timer already running.', app.LOGLabel.Text);
    end


        end

        % Value changed function: HighLowButton
        function ToggleBtnState(app, event)
            value = app.HighLowButton.Value;
            
            app.toggleStat = value;  % Update internal state
            
            % Update the toggle state on the backend
            try
                % Construct the payload with the toggle state
                toggleData = struct('new_value', value);
                % Assume endpoint '/toggle' is used for updating toggle state.
                toggleResponse = webwrite([app.baseUrl '/parameter/toggle'], toggleData, ...
                    weboptions('MediaType','application/json','RequestMethod','put'));
                % Optionally, update LOGLabel with the response status.
                app.LOGLabel.Text = sprintf('Toggle update:\n%s', toggleResponse.status);
            catch ME
                app.LOGLabel.Text = ['Error updating toggle state: ' ME.message];
            end
            app.plotHighPassFilter;
        end

        % Value changed function: Resistor1inkEditField
        function resistorValueChanged(app, event)
    value = app.Resistor1inkEditField.Value;
    try
        data = struct('new_value', value);
        response = webwrite([app.baseUrl '/parameter/Resistor1'], data, ...
            weboptions('MediaType','application/json','RequestMethod','put'));
        app.LOGLabel.Text = sprintf('Resistor1 update: %s', response.status);
    catch ME
        app.LOGLabel.Text = sprintf('Error updating Resistor1: %s', ME.message);
    end
            app.plotHighPassFilter();
    
        end

        % Value changed function: Capacitor1nFEditField
        function capacitorVaueChanged(app, event)
    value = app.Capacitor1nFEditField.Value;
    try
        data = struct('new_value', value);
        response = webwrite([app.baseUrl '/parameter/Capacitor1'], data, ...
            weboptions('MediaType','application/json','RequestMethod','put'));
        app.LOGLabel.Text = sprintf('Capacitor1 update: %s', response.status);
    catch ME
        app.LOGLabel.Text = sprintf('Error updating Capacitor1: %s', ME.message);
    end
             app.plotHighPassFilter();
   
        end

        % Button down function: UIAxes
        function plotHighPassFilter(app, event)
               R_k = app.Resistor1inkEditField.Value;  % Resistor value in kilo-ohms
    C_nF = app.Capacitor1nFEditField.Value;   % Capacitor value in nanoFarads

    % Convert values to ohms and farads
    R = R_k * 1e3;
    C = C_nF * 1e-9;
    
    % Define a frequency range for plotting (e.g., 10 Hz to 1 MHz)
    f = logspace(1, 6, 500);
    omega = 2*pi*f;
    
    % Depending on the toggle state, select the transfer function
    if app.toggleStat
        % High-pass filter: H(jω) = (jωRC)/(1 + jωRC)
        H = (1j * omega * R * C) ./ (1 + 1j * omega * R * C);
        plotTitle = 'High Pass Filter Response';
    else
        % Low-pass filter: H(jω) = 1/(1 + jωRC)
        H = 1 ./ (1 + 1j * omega * R * C);
        plotTitle = 'Low Pass Filter Response';
    end
    
    % Calculate magnitude (in dB) and phase (in degrees)
    magnitude_dB = 20*log10(abs(H));
    phase_deg = angle(H)*180/pi;
    
    % Clear previous plots in UIAxes
    cla(app.UIAxes);
    
    % Plot using dual y-axes: left for magnitude, right for phase.
    yyaxis(app.UIAxes, 'left');
    semilogx(app.UIAxes, f, magnitude_dB, 'b', 'LineWidth', 2);
    ylabel(app.UIAxes, 'Magnitude (dB)');
    
    yyaxis(app.UIAxes, 'right');
    semilogx(app.UIAxes, f, phase_deg, 'r', 'LineWidth', 2);
    ylabel(app.UIAxes, 'Phase (deg)');
    
    xlabel(app.UIAxes, 'Frequency (Hz)');
    title(app.UIAxes, plotTitle);
    grid(app.UIAxes, 'on');

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {522, 522};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {219, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InitAPPButton = uibutton(app.LeftPanel, 'push');
            app.InitAPPButton.ButtonPushedFcn = createCallbackFcn(app, @configStartup, true);
            app.InitAPPButton.Text = 'Init APP';
            app.HighLowButton = uibutton(app.LeftPanel, 'state');
            app.HighLowButton.ValueChangedFcn = createCallbackFcn(app, @ToggleBtnState, true);
            app.HighLowButton.Text = 'High/Low';
            app.Resistor1inkEditFieldLabel = uilabel(app.LeftPanel);
            app.Resistor1inkEditFieldLabel.Text = 'Resistor 1 (in k)';
            app.Resistor1inkEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Resistor1inkEditField.ValueChangedFcn = createCallbackFcn(app, @resistorValueChanged, true);
            app.Capacitor1nFEditFieldLabel = uilabel(app.LeftPanel);
            app.Capacitor1nFEditFieldLabel.Text = 'Capacitor 1 (nF)';
            app.Capacitor1nFEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Capacitor1nFEditField.ValueChangedFcn = createCallbackFcn(app, @capacitorVaueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Response')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @plotHighPassFilter, true);
            app.LOGLabel = uilabel(app.RightPanel);
            app.LOGLabel.Text = 'LOG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = arduinoAMC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end